classdef FinalProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        Menu_4              matlab.ui.container.Menu
        Menu_3              matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        Label_12            matlab.ui.control.Label
        Label_11            matlab.ui.control.Label
        Label_10            matlab.ui.control.Label
        Label_9             matlab.ui.control.Label
        Label_8             matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        ProcessButton       matlab.ui.control.Button
        ReportC_TextArea    matlab.ui.control.TextArea
        ReportB_TextArea    matlab.ui.control.TextArea
        ReportA_TextArea    matlab.ui.control.TextArea
        ImageC_After_Axes   matlab.ui.control.UIAxes
        ImageC_Before_Axes  matlab.ui.control.UIAxes
        ImageB_After_Axes   matlab.ui.control.UIAxes
        ImageB_Before_Axes  matlab.ui.control.UIAxes
        ImageA_After_Axes   matlab.ui.control.UIAxes
        ImageA_Before_Axes  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % --- 將 App 視窗置中於螢幕 ---

            % 1. 取得螢幕的尺寸 [left, bottom, width, height]
            %    get(0, 'ScreenSize') 是取得主螢幕資訊的標準指令
            screen_size = get(0, 'ScreenSize');
            screen_width = screen_size(3);
            screen_height = screen_size(4);
            
            % 2. 取得您 App 視窗目前的尺寸
            app_width = app.UIFigure.Position(3);
            app_height = app.UIFigure.Position(4);
            
            % 3. 計算能讓視窗置中的左下角 (left, bottom) 座標
            new_left = (screen_width - app_width) / 2;
            new_bottom = (screen_height - app_height) / 2;
            
            % 4. 設定 App 的新位置
            %    [left, bottom, width, height]
            app.UIFigure.Position = [new_left, new_bottom, app_width, app_height];
            % 【新增】: 從設定檔讀取並設定 App 標題
            app.UIFigure.Name = env.config.APP_TITLE;
        end

        % Callback function: Menu_2, ProcessButton
        function ProcessButtonPushed(app, event)
            
            pause(0.2);

            % --- 【焦點修正 Round 1】---
            % 在開啟任何對話框之前，先強制將主 App 視窗帶到最前方。
            % 這能確保接下來開啟的對話框會附屬在我們的 App 之上。
            figure(app.UIFigure);
            
            % 1. 開啟檔案選擇對話框
            [files, path] = uigetfile(env.config.SUPPORTED_IMAGE_FORMATS, ...
                                  '請選擇三張圖片檔案', ...
                                  'MultiSelect', 'on');
            
            % --- 【焦點修正 Round 2】---
            % 在選擇完檔案、對話框關閉之後，再次強制將主 App 視窗帶到最前方。
            % 這能確保焦點正確地回到我們的 App，而不是被其他程式搶走。
            figure(app.UIFigure);
            
            % 檢查使用者是否正確選擇檔案
            if ~iscell(files) || isequal(files, 0) % isequal(files,0) 處理使用者按下取消的情況
                % 如果使用者按下取消，就不顯示錯誤訊息，直接靜默返回
                return;
            end
            if numel(files) ~= 3
                uialert(app.UIFigure, '請務必選擇剛好 3 張圖片！', '選擇錯誤');
                return;
            end
            
            % 準備好要操作的元件名稱列表
            before_axes = {app.ImageA_Before_Axes, app.ImageB_Before_Axes, app.ImageC_Before_Axes};
            after_axes = {app.ImageA_After_Axes, app.ImageB_After_Axes, app.ImageC_After_Axes};
            report_areas = {app.ReportA_TextArea, app.ReportB_TextArea, app.ReportC_TextArea};
            output_labels = {'A', 'B', 'C'};
        
            % 進入忙碌狀態
            app.UIFigure.Pointer = 'watch';
            drawnow;
            
            try
                % ... (後續的 for 迴圈與影像處理邏輯完全不變) ...
                for i = 1:3
                    image_path = fullfile(path, files{i});
                    
                    original_image = imread(image_path);
                    imshow(original_image, 'Parent', before_axes{i});
                    
                    [final_image, ~, report] = functions.process_image(image_path);
                    
                    imshow(final_image, 'Parent', after_axes{i});
                    
                    lighting_status = report.lighting;
                    noise_status = report.noise;
                    if report.blur, blur_status = '是'; else, blur_status = '否'; end
                    
                    steps = report.applied_steps;
                    steps_str = '';
                    for k = 1:length(steps)
                        steps_str = [steps_str, sprintf('- %s\n', steps{k})];
                    end
                    
                    report_text = sprintf([...
                        '--- 影像診斷 ---\n' ...
                        '光線問題: %s\n' ...
                        '雜訊問題: %s\n' ...
                        '是否模糊: %s\n\n' ...
                        '--- 套用處理步驟 ---\n%s'], ...
                        lighting_status, noise_status, blur_status, steps_str);
                    report_areas{i}.Value = report_text;
                    
                    if ~exist(env.config.OUTPUT_FOLDER_NAME, 'dir')
                        mkdir(env.config.OUTPUT_FOLDER_NAME);
                    end
                    output_filename = sprintf(env.config.PROCESSED_IMAGE_FILENAME_FORMAT, env.config.STUDENT_ID, output_labels{i});
                    full_output_path = fullfile(env.config.OUTPUT_FOLDER_NAME, output_filename);
                    imwrite(final_image, full_output_path);
                end
                
                uialert(app.UIFigure, ['所有圖片處理完成，並已儲存至 ', env.config.OUTPUT_FOLDER_NAME, ' 資料夾！'], '成功');
        
            catch ME % 錯誤處理
                uialert(app.UIFigure, ['處理過程中發生錯誤: ' ME.message], '錯誤');
            end
        
            % 恢復正常狀態
            app.UIFigure.Pointer = 'arrow';

        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            selection = uiconfirm(app.UIFigure, ...
            'Do you want to close the app?', 'Close request', ...
            'Options', {'OK','Cancel'}, ...
            'DefaultOption',2);
            if strcmp(selection, 'OK')
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '檔案';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.Menu_2.Text = '開啟檔案並處理';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '應用程式';
            app.Menu_3 = uimenu(app.Menu_4);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '退出';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageA_Before_Axes = uiaxes(app.GridLayout);
            zlabel(app.ImageA_Before_Axes, 'Z')
            app.ImageA_Before_Axes.XTick = [];
            app.ImageA_Before_Axes.YTick = [];
            app.ImageA_After_Axes = uiaxes(app.GridLayout);
            app.ImageA_After_Axes.XTick = [];
            app.ImageA_After_Axes.YTick = [];
            app.ImageB_Before_Axes = uiaxes(app.GridLayout);
            app.ImageB_Before_Axes.XTick = [];
            app.ImageB_Before_Axes.YTick = [];
            app.ImageB_After_Axes = uiaxes(app.GridLayout);
            app.ImageB_After_Axes.XTick = [];
            app.ImageB_After_Axes.YTick = [];
            app.ImageC_Before_Axes = uiaxes(app.GridLayout);
            app.ImageC_Before_Axes.XTick = [];
            app.ImageC_Before_Axes.YTick = [];
            app.ImageC_After_Axes = uiaxes(app.GridLayout);
            zlabel(app.ImageC_After_Axes, 'Z')
            app.ImageC_After_Axes.XTick = [];
            app.ImageC_After_Axes.YTick = [];
            app.ReportA_TextArea = uitextarea(app.GridLayout);
            app.ReportA_TextArea.Editable = 'off';
            app.ReportB_TextArea = uitextarea(app.GridLayout);
            app.ReportB_TextArea.Editable = 'off';
            app.ReportC_TextArea = uitextarea(app.GridLayout);
            app.ReportC_TextArea.Editable = 'off';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ProcessButton = uibutton(app.GridLayout2, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'open_file.png');
            app.ProcessButton.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '圖 1 -處理後結果';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '圖 2 -處理後結果';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '圖 3 - 處理後結果';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '圖 1 - 輸入原圖';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '圖 2 - 輸入原圖';
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '圖 3 - 輸入原圖';
            app.Label_10 = uilabel(app.GridLayout);
            app.Label_10.Text = '圖 1 -診斷結果';
            app.Label_11 = uilabel(app.GridLayout);
            app.Label_11.Text = '圖 2 -診斷結果';
            app.Label_12 = uilabel(app.GridLayout);
            app.Label_12.Text = '圖 3 - 診斷結果';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end